classdef LeafVeinCNN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LeafVeinGUI                   matlab.ui.Figure
        LoadPanel                     matlab.ui.container.Panel
        LoadImageButton               matlab.ui.control.Button
        ImageLabel                    matlab.ui.control.Label
        micronperpixelEditFieldLabel  matlab.ui.control.Label
        MicronPerPixelEditField       matlab.ui.control.NumericEditField
        DimensionsTextAreaLabel       matlab.ui.control.Label
        DimensionsTextArea            matlab.ui.control.TextArea
        TargetDropDownLabel           matlab.ui.control.Label
        TargetDropDown                matlab.ui.control.DropDown
        rescalefactorLabel            matlab.ui.control.Label
        ScaleFactorEditField          matlab.ui.control.NumericEditField
        DisplayPanel                  matlab.ui.container.Panel
        DisplayAxes                   matlab.ui.control.UIAxes
        ProgramPanel                  matlab.ui.container.Panel
        CNNRunButton                  matlab.ui.control.Button
        CNNSaveButton                 matlab.ui.control.Button
        EnsembleDropDownLabel         matlab.ui.control.Label
        CNNEnsembleDropDown           matlab.ui.control.DropDown
        PixelStrideLabel              matlab.ui.control.Label
        CNNStrideSlider               matlab.ui.control.Slider
        DisplayControlsPanel          matlab.ui.container.Panel
        DisplayImageLabel             matlab.ui.control.Label
        DisplayImageDropDown          matlab.ui.control.DropDown
        ProcessingPanel               matlab.ui.container.Panel
        CropButton                    matlab.ui.control.Button
        ClaheSaveButton               matlab.ui.control.Button
        ClaheLoadButton               matlab.ui.control.Button
        ClaheButton                   matlab.ui.control.Button
        RadiusLabel                   matlab.ui.control.Label
        ClaheRadiuslEditField         matlab.ui.control.NumericEditField
        GainLabel                     matlab.ui.control.Label
        ClaheGainEditField            matlab.ui.control.NumericEditField
        ClaheSkipCheckBox             matlab.ui.control.CheckBox
        ChannelDropDownLabel          matlab.ui.control.Label
        ChannelDropDown               matlab.ui.control.DropDown
        StatusLabel                   matlab.ui.control.Label
        AnalysisPanel                 matlab.ui.container.Panel
        AnalysisButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        images % Structure to hold all the processed images
        param % Structure for all parameters
        var % structure for variables
    end
    
    methods (Access = private)
        
        function funcSkipClahe(app)
            value = app.ClaheSkipCheckBox.Value;
            if value == 1
                h = app.ProcessingPanel.Children;
                set(h,'Enable','off');
                app.CropButton.Enable = 'on';
                app.ClaheSkipCheckBox.Enable = 'on';
                app.images.Clahe = app.images.crop;
            else
                h = app.ProcessingPanel.Children;
                set(h,'Enable','on');
                app.images.Clahe = [];
            end
        end
        
        function funcLoadImage(app,target)
            [app.var.fname,app.var.dir_in,~] = uigetfile({'*.png'});
            cd(app.var.dir_in)
            app.StatusLabel.Text = 'Loading image. Please wait...';drawnow
            app.images.(target) = imread(fullfile(app.var.dir_in,app.var.fname));
            app.var.info = imfinfo(fullfile(app.var.dir_in,app.var.fname));
            app.DimensionsTextArea.Value = {['Width: ' num2str(app.var.info.Width)]; ['Height: ' num2str(app.var.info.Height)]; ['BitDepth: ' num2str(app.var.info.BitDepth)]};
            funcImageDisplay(app, target)
            app.ImageLabel.Text = app.var.fname;
            app.StatusLabel.Text = ['Image ' app.var.fname ' loaded'];
        end
        
        function funcImageDisplay(app, target)
            if ~contains(app.DisplayImageDropDown.Items,target)
                app.DisplayImageDropDown.Items = [app.DisplayImageDropDown.Items,target];
            end
            app.DisplayImageDropDown.Value = target;
            switch target
                case 'CNNoverlay'
                    overlay = imoverlay(app.images.crop,bwperim(mean(app.images.CNN,3)>0.65),'r');
                    overlay = imoverlay(overlay,bwperim(mean(app.images.CNN,3)>0.5),'g');
                    overlay = imoverlay(overlay,bwperim(mean(app.images.CNN,3)>0.45),'b');
                    imshow(overlay,'Parent',app.DisplayAxes)
                case 'sk'
                    overlay = imoverlay(app.images.crop,app.images.sk,'g');
                    overlay = imoverlay(overlay,bwperim(mean(app.images.CNN,3)>0.65),'r');
                    imshow(overlay,'Parent',app.DisplayAxes)
                otherwise
                    if size(app.images.(target),3) ==1 || size(app.images.(target),3) == 3
                        imshow(app.images.(target),[],'Parent',app.DisplayAxes);
                    else
                        montage(app.images.(target),'Parent',app.DisplayAxes)
                    end
            end
            axis(app.DisplayAxes,'tight')
            app.StatusLabel.Text = ['Displaying image ' target];
        end
        
        function im_out = funcChannelSelect(app, im_in)
            app.param
            %offset = app.param.ChannelOffset
            offset = 128;
            % process the channels
            switch app.param.Channel
                case 'mean'
                    temp = mean(im_in,3);
                case 'max'
                    temp = max(im_in,[],3);
                case 'min'
                    temp = min(im_in,[],3);
                case 'R'
                    temp = im_in(:,:,1);
                case 'G'
                    temp = im_in(:,:,2);
                case 'B'
                    temp = im_in(:,:,3);
                case 'R-G'
                    temp = imlincomb(0.5,im_in(:, :, 1),-0.5,im_in(:, :, 2),offset);
                case 'R-B'
                    temp = imlincomb(0.5,im_in(:, :, 1),-0.5,im_in(:, :, 3),offset);
                case 'G-R'
                    temp = imlincomb(0.5,im_in(:, :, 2),-0.5,im_in(:, :, 1),offset);
                case 'G-B'
                    temp = imlincomb(0.5,im_in(:, :, 2),-0.5,im_in(:, :, 3),offset);
                case 'B-R'
                    temp = imlincomb(0.5,im_in(:, :, 3),-0.5,im_in(:, :, 1),offset);
                case 'B-G'
                    temp = imlincomb(0.5,im_in(:, :, 3),-0.5,im_in(:, :, 2),offset);
                case 'R/G'
                    temp = (single(im_in(:, :, 1))+0.5) ./ single(im_in(:, :, 2)+0.5);
                case 'R/B'
                    temp = (single(im_in(:, :, 1))+0.5) ./ single(im_in(:, :, 3)+0.5);
                case 'G/R'
                    temp = (single(im_in(:, :, 2))+0.5) ./ single(im_in(:, :, 1)+0.5);
                case 'G/B'
                    temp = (single(im_in(:, :, 2))+0.5) ./ single(im_in(:, :, 3)+0.5);
                case 'B/R'
                    temp = (single(im_in(:, :, 3))+0.5) ./ single(im_in(:, :, 1)+0.5);
                case 'B/G'
                    temp = (single(im_in(:, :, 3))+0.5) ./ single(im_in(:, :, 2)+0.5);
                case 'R^2/G'
                    temp = (single(im_in(:, :, 1))+0.5).^2 ./ single(im_in(:, :, 2)+0.5);
                case 'R^2/B'
                    temp = (single(im_in(:, :, 1))+0.5).^2 ./ single(im_in(:, :, 3)+0.5);
                case 'G^2/R'
                    temp = (single(im_in(:, :, 2))+0.5).^2 ./ single(im_in(:, :, 1)+0.5);
                case 'G^2/B'
                    temp = (single(im_in(:, :, 2))+0.5).^2 ./ single(im_in(:, :, 3)+0.5);
                case 'B^2/R'
                    temp = (single(im_in(:, :, 3))+0.5).^2 ./ single(im_in(:, :, 1)+0.5);
                case 'B^2/G'
                    temp = (single(im_in(:, :, 3))+0.5).^2 ./ single(im_in(:, :, 2)+0.5);
            end
            % normalise between 0 and 1
            temp = mat2gray(temp);
            % rescale to 1%-99%
            temp = imadjust(temp);
            % convert to uint8
            im_out = uint8(255.*temp);
        end
        
        
        function [bw_cnn, skfinal, skLoop, skTree] = fnc_skeleton(app,threshold)
            im = mean(app.images.CNN,3);
            % convert to a binary image
            bw_cnn = imbinarize(im,threshold);
            % smooth the binary image
            bw_cnn = medfilt2(bw_cnn,[3 3]);
            % keep the connected component
            bw_cnn = bwareafilt(bw_cnn,1);
            % calculate the distance transform as the input to a watershed segmentation
            D = bwdist(~bw_cnn,'Euclidean');
            W = watershed(D,4);
            % get the watershed skeleton comprising only loops
            skLoop = W == 0;
            % thin to a single pixel skeleton
            skLoop = bwmorph(skLoop,'thin',Inf);
            % find any isolated loops
            skRing = skLoop & ~bwareafilt(skLoop,1);
            % exclude rings toughing the border
            skRing = imclearborder(skRing);
            if any(skRing(:))
                % fill the loops
                skLoopFill = imfill(skRing,'holes');
                % punch the loop skeleton back out. This ensure two touching loops are
                % treated separately
                skLoopFill = skLoopFill & ~skRing;
                % erode to a single point in the middle of the loop
                skLoopPoints = bwulterode(skLoopFill);
                % use these points to fill the loop in the binary image
                bw_cnn_fill = imfill(bw_cnn,find(skLoopPoints));
                % thin the binary image to a single pixel skeleton
                sk = bwmorph(bw_cnn_fill,'thin',Inf);
            else
                sk =  bwmorph(bw_cnn,'thin',Inf);
            end
            % only keep the largest connected component
            sk = bwareafilt(sk,1);
            % find tree regions in the thinned skeleton as the difference after spur
            % removal
            skSp = bwmorph(sk,'spur',Inf);
            skTree = xor(sk,skSp);
            % remove single pixel spurs now present as isolated points
            skTree = bwmorph(skTree,'clean');
            if any(skRing(:))
                % punch out the isolated loops
                skTree = skTree & ~skLoopFill;
                % add back in the watershed loop
                skTree = skTree | skRing;
            end
            % get the endpoints of the thinned skeleton, representing the free end
            % veins
            epsk = bwmorph(sk,'endpoints');
            % find the endpoints at the root of the tree
            epskTree = bwmorph(skTree,'endpoints');
            epskTree = xor(epsk,epskTree);
            % only work with endpoints that are not already connected to
            % the watershed skeleton
            connected = bwareafilt(epskTree | skLoop,1);
            epskTree = epskTree & ~connected;
            % get the feature map from the watershed skeleton to find the nearest pixel
            % to connect to
            [~,skW_idx] = bwdist(skLoop);
            % get the pixel co-ordinates for the nearest point on the skeleton
            [y1,x1] = ind2sub(size(skRing),skW_idx(epskTree));
            % get the pixel co-ordinates for the endpoints
            [y2,x2] = find(epskTree);
            % use the bresenham algorithm to find the best pixel line
            [x,y] = arrayfun(@(x1,y1,x2,y2) bresenham(x1,y1,x2,y2),x1,y1,x2,y2,'uniformoutput',0);
            % get the index of the line points
            P = cellfun(@(x,y) sub2ind(size(skLoop),y,x),x,y,'UniformOutput',0);
            % add the lines into the tree skeleton
            skTree(cat(1,P{:})) = 1;
            if any(skRing(:))
                % remove any parts of the skeleton overlapping the loops
                skTree(skLoopFill) = 0;
                % remove the rings from the loop skeleton as they will be included in
                % the tree skeleton
                skLoop(skRing) = 0;
            end
            % add the watershed skeleton back in
            skfinal = skTree | skLoop;
            % keep the largest connected component
            skfinal = bwareafilt(skfinal,1);
        end
        
function [edgelist,edgeim] = fnc_resolve_self_loops(app,edgelist,edgeim)
D_idx = 1;
[nY,nX] = size(edgeim);
while ~isempty(D_idx)
    % F_idx is the linear index to the first pixel in each edge array
    F_idx = cellfun(@(x) sub2ind([nY,nX],x(1,1),x(1,2)),edgelist);
    % L_idx is the linear index to the last pixel in each edge array
    L_idx = cellfun(@(x) sub2ind([nY,nX],x(end,1),x(end,2)),edgelist);
    % check if nodei and nodej are equal
    D_idx = find(F_idx==L_idx);
    % See if there are any loops
    if ~isempty(D_idx)
        % Go through each loop and split it in two if it is long enough,
        % adding an extra edge at the end of the edgelist. If the duplicate
        % edge is less than 10 pixels long, record the index and then delete it
        % once all dupicates have been resolved after the while loop has
        % finished (to make sure the indexing doesn't change in the main
        % loop)
        remove_idx = [];
        %h = waitbar(0,['Resolving loops for timepoint ' num2str(iN) '. Please wait...']);
        for loop = 1:length(D_idx)
            % get the pixels for the first duplicate edge
            pix = edgelist{D_idx(loop)};
            % find the middle of the edge
            midnode = floor(length(pix)./2);
            if midnode > 5
                % replace this edge with the first half of the original edge
                edgelist{D_idx(loop)} = pix(1:midnode,:);
                % find the current number of edges at this time point
                n = length(edgelist)+1;
                % add a new edge at the end of the list with the second
                % half of the edge, including an overlap
                edgelist{n} = pix(midnode:end,:);
                % get the pixel co-ordinates for the new edge
                idx = sub2ind([nY,nX],edgelist{D_idx(loop)}(:,1),edgelist{D_idx(loop)}(:,2));
                % write in the new edge to the edgeim
                edgeim(idx) = n;
            else
                % store the index of the edge to be deleted, but don't
                % delete yet as the indexing will alter for the other edges
                remove_idx(end+1) = D_idx(loop);
                %                 % make sure the pixel has the value of the original edge in the
                %                 % edge image
                %                 OE = edgeim(D_idx(loop));
                %                 idx = sub2ind([nY,nX],edgelist{OE}(:,1),edgelist{OE}(:,2));
                %                 % write in the old edge back into the edgeim
                %                 edgeim(idx) = OE;
            end
        end
        % remove the duplicate edges
        if ~isempty(remove_idx)
            % disp('removing duplicate edges')
            edgelist(remove_idx) = [];
        end
    end
end
end

function [edgelist,edgeim] = fnc_resolve_duplicates(app,edgelist,edgeim)
D_idx = 1;
iteration = 0;
[nY,nX] = size(edgeim);
while ~isempty(D_idx)
    iteration = iteration+1;
    % F_idx is the linear index to the first pixel in each edge array
    F_idx = cellfun(@(x) sub2ind([nY,nX],x(1,1),x(1,2)),edgelist);
    % L_idx is the linear index to the last pixel in each edge array
    L_idx = cellfun(@(x) sub2ind([nY,nX],x(end,1),x(end,2)),edgelist);
    % combine to give the endpoints (EP) of the edge, i.e. nodei and nodej
    EP = [F_idx' L_idx'];
    % Order the rows of nodei and nodej to be smallest first. This makes sure
    % that duplicates are found whichever way round the nodes were
    % initially, but doesn't alter the ordering of the edges
    EP_ordered = [min(EP, [],2) max(EP,[],2)];
    % find the unique rows (EP_unique) with the corresponding index into
    % the original node pairs (ia). The ia index skips any row
    % that is duplicated. The ic index picks the value from EP_unique to
    % recreate the original array. We can use this index to find the index
    % of the first duplicate node.
    [~,ia,ic] = unique(EP_ordered, 'rows');
    % Compare the ia index with a full list of node IDs to give the index
    % of the edges that were skipped
    D_idx = setdiff(1:length(EP),ia);
    % See if there are any duplicate edges
    if ~isempty(D_idx)
        % Go through each loop and split it in two if it is long enough,
        % adding an extra edge at the end of the edgelist. If the duplicate
        % edge is only one pixel long, record the index and then delete it
        % once all dupicates have been resolved after the while loop has
        % finished (to make sure the indexing doesn't change in the main
        % loop)
        remove_idx = [];
        %h = waitbar(0,['Resolving loops for timepoint ' num2str(iN) '. Please wait...']);
        for loop = 1:length(D_idx)
            % get the pixels for the first duplicate edge
            pix = edgelist{D_idx(loop)};
            % find the middle of the edge
            midnode = floor(length(pix)./2);
            if midnode > 1
                % replace this edge with the first half of the original edge
                edgelist{D_idx(loop)} = pix(1:midnode,:);
                % find the current number of edges at this time point
                n = length(edgelist)+1;
                % add a new edge at the end of the list with the second
                % half of the edge, including an overlap
                edgelist{n} = pix(midnode:end,:);
                % get the pixel co-ordinates for the new edge
                idx = sub2ind([nY,nX],edgelist{D_idx(loop)}(:,1),edgelist{D_idx(loop)}(:,2));
                % write in the new edge to the edgeim
                edgeim(idx) = n;
            else
                % store the index of the edge to be deleted, but don't
                % delete yet as the indexing will alter for the other edges
                remove_idx(end+1) = D_idx(loop);
                % make sure the pixel has the value of the original edge in the
                % edge image
                OE = ic(D_idx(loop));
                idx = sub2ind([nY,nX],edgelist{OE}(:,1),edgelist{OE}(:,2));
                % write in the old edge back into the edgeim
                edgeim(idx) = OE;
            end
        end
        % remove the duplicate edges
        if ~isempty(remove_idx)
            % disp('removing duplicate edges')
            edgelist(remove_idx) = [];
        end
    end
end
end

function [G_veins,edgelist] = fnc_weighted_graph(app,edgelist,W_pixels,skTree)
    [nY,nX] = size(W_pixels);
    % calculate the node indices to account for the edges added and removed
    % I_idx is the linear index to the first pixel in each edge
    I_idx = cellfun(@(x) sub2ind([nY nX],x(1,1),x(1,2)),edgelist);
    % J_idx is the linear index to the last pixel in each edge array
    J_idx = cellfun(@(x) sub2ind([nY nX],x(end,1),x(end,2)),edgelist);
    % P_idx is the linear index of each pixel in the cell array of edges
    P_idx = cellfun(@(x) sub2ind([nY nX],x(:,1),x(:,2)),edgelist,'UniformOutput',0);
    % N_pix is the number of pixels in the edge list
    N_pix = cellfun(@(x) numel(x(:,1)), edgelist);
    % M_pix is the mid pixel
    M_pix = cellfun(@(x,y) x(round(y/2)), P_idx, num2cell(N_pix));
    % Get various metrics associated with each edge from the various processing
    % stages. Initially the values are returned as cell arrays with individual
    % values for each pixel. These are then summarised to give a single value
    % for each edge for that metric.
    % Get the length as the difference in euclidean distance between pixels
    L_val = cellfun(@(x) hypot(diff(x(:,1)),diff(x(:,2))),edgelist,'UniformOutput',0);
    L_sum = cellfun(@sum, L_val);
    % get the orientation using atan2
    O_val = cellfun(@(x) atan2(x(1,1)-x(end,1),x(1,2)-x(end,2)),edgelist,'UniformOutput',1);
    % Get the width
    W_val = cellfun(@(x) W_pixels(x),P_idx,'UniformOutput',0);
    W_mean = cellfun(@mean, W_val);
    % Set the edge ID
    nEdges = length(I_idx);
    EName = (1:nEdges)';
    % set all edges ('E') to belong to a loop ('L') initially
    EType = repmat({'EL'},nEdges,1);
    % Set any edges that belong to a tree with 'T'. Use M_pix to sample the
    % middle point in the skeleton
    EType(skTree(M_pix)) = {'ET'};
    % set the edge weight to the average width
    E_weight = W_mean;
    % initially the nodes are empty
    nodei = zeros(nEdges,1);
    nodej = zeros(nEdges,1);
    node_idx = unique([I_idx'; J_idx']);
    % combine the edge metrics into an EdgeTable
    names = {'EndNodes', 'node_Idx', 'Name', 'Type', 'Weight', ...
        'Width_initial',  ...
        'Length_initial', ...
        'Orientation_initial', ...
        'N_pix', 'M_pix'};
    
    EdgeTable = table([nodei, nodej], [I_idx', J_idx'], EName, EType, E_weight', ...
        W_mean', ...
        L_sum', ...
        O_val', ...
        N_pix', M_pix', ...
        'VariableNames', names);
    % now collect all the node indices to construct a NodeTable. Get the index
    % of all the nodes present in i or j
    node_ID = (1:length(node_idx))';
    node_type = repmat({'E'},length(node_idx),1);
    % get the coordinates of each node
    [y_pix,x_pix] = ind2sub([nY nX],node_idx);
    % Construct the full NodeTable (note at this point the co-ordinates are all
    % in pixels
    NodeTable = table(node_ID, node_idx, node_type, x_pix, y_pix, ...
        'VariableNames',{'node_ID' 'node_Idx' 'node_Type' 'node_X_pix' 'node_Y_pix'});
    % Assemble the MatLab graph object
    % Convert the linear node index into a node ID
    [~,EdgeTable{:,'EndNodes'}(:,1)] = ismember(EdgeTable{:,'node_Idx'}(:,1), node_idx);
    [~,EdgeTable{:,'EndNodes'}(:,2)] = ismember(EdgeTable{:,'node_Idx'}(:,2), node_idx);
    G_veins = graph(EdgeTable, NodeTable);
    % The graph automatically sorts edge rows based on node i. The
    % original edge index is kept in Name. Reoder the edgelist to match the
    % graph order, including nodei <-> node j
    edgelist = edgelist(G_veins.Edges.Name);
    % I_idx is the linear index to the first pixel in each edge array
    I_idx = cellfun(@(x) sub2ind([nY nX],x(1,1),x(1,2)),edgelist);
    % Find which EndNodes in the edge table do not start with nodei, and have
    % therefore been re-ordered
    switch_idx = G_veins.Nodes{G_veins.Edges.EndNodes(:,1),'node_Idx'} ~= I_idx';
    % flip the order of pixels in the edgelist to match
    edgelist(switch_idx) = cellfun(@(x) flipud(x), edgelist(switch_idx), 'UniformOutput',0);
end

function [G_veins,edgelist_center] = fnc_refine_width(app,G_veins,edgelist,im,im_cnn,W_pixels)
    [nY,nX] = size(W_pixels);
    % Calculate the node degree
    G_veins.Nodes.node_Degree = degree(G_veins);
    % Get the edges from the graph
    nN = numnodes(G_veins);
    [i,j] = findedge(G_veins);
    % Construct a sparse, symmetric weighted adjacency matrix from the graph
    % widths. The values have to be in double precision for
    % sparse to work.
    width = G_veins.Edges.Width_initial;
    A = sparse(i,j,double(width),nN,nN);
    % make the matrix symmetric
    A = A + A.' - diag(diag(A));
    % Calculate the major edge width and the edge index incident at the node
    % (initially in double precision inherited from the sparse matrix
    [edge_Maj, max_idx] = max(A,[],2); % column format to match the table later
    edge_Maj = full(edge_Maj);
    % Calculate the width of the penultimate edge width by removing the max and
    % recalculating the max for the remainder
    rows = (1:size(A,1))';
    mx_idx = sub2ind(size(A),rows,max_idx);
    Amid = A;
    Amid(mx_idx) = 0;
    G_veins.Nodes.node_Mid = full(max(Amid,[],2));
    % Calculate the minimum edge width. To calculate the initial min of a
    % sparse matrix, take the negative and then add back the maximum
    [nnzr, nnzc] = find(A);
    B = -A + sparse(nnzr,nnzc,max(edge_Maj),size(A,1),size(A,2));
    mn = max(B,[],2);
    G_veins.Nodes.node_Min = -(mn-max(edge_Maj));
    G_veins.Nodes.node_Maj = edge_Maj;
    % get the degree for nodei and nodej
    NDegI = G_veins.Nodes{G_veins.Edges.EndNodes(:,1),'node_Degree'};
    NDegJ = G_veins.Nodes{G_veins.Edges.EndNodes(:,2),'node_Degree'};
    % get the maximum edge weight for nodei and nodej
    NMajI = G_veins.Nodes{G_veins.Edges.EndNodes(:,1),'node_Maj'};
    NMajJ = G_veins.Nodes{G_veins.Edges.EndNodes(:,2),'node_Maj'};
    % Get the penultimate edge weight for nodei and nodej
    NMidI = G_veins.Nodes{G_veins.Edges.EndNodes(:,1),'node_Mid'};
    NMidJ = G_veins.Nodes{G_veins.Edges.EndNodes(:,2),'node_Mid'};
    % Get the number of pixels in the edge
    N_pix = G_veins.Edges.N_pix;
    % Get the length as the difference in euclidean distance between pixels
    L_val = cellfun(@(x) hypot(diff(x(:,1)),diff(x(:,2))),edgelist,'UniformOutput',0);
    % L_sum is the cumulative length of the edge starting from nodei
    L_sum = cellfun(@(x) cumsum(x),L_val,'UniformOutput',0);
    % Initially use half the average (NAveI) or maximum (NMajI) at
    % the node as the overlap distance to avoid. Limit the index to
    % 50% of the number of pixels minus 1 (to ensure that there is
    % a minimum length of edge remaining). Find where the index
    % where cumulative length equals or exceeeds half the overlap
    % width
    L_idx = cellfun(@(x,y) find(x>=y/2,1),L_sum,num2cell(NMajI)','UniformOutput',0);
    % empty cells means the overlap distance is longer than the
    % edge. Set these values to be infinite initially, but will be
    % set to half the number of pixels
    L_max = cellfun('isempty',L_idx);
    L_idx(L_max) = {inf};
    % constrain the maximum offset to be half the number of pixels - 1
    % for edges with overlap to make sure there is some edge left.
    first = min(cell2mat(L_idx)',max(round(N_pix.*0.5)-1,1));
    first(first<1) = 1;
    % If the edge width is equal to or greater than the penultimate width of
    % all edges at the node i, or the node degree is 2 or less, then keep the
    % full length of the edge. i.e. set the starting index to 1;
    idx =  width >= NMidI | NDegI <= 2;
    %idx =  NDegI <= 2 & NPerimI == 0;
    first(idx) = 1;
    % Repeat for node j, using half the largest edge width as an
    % index into the array from a minimum of some fraction of the
    % number of pixels + 1 (to ensure that there is a minimum edge
    % length remaining). Switch the order to set nodej at the start
    % for every edge
    L_val = cellfun(@(x) flipud(x),L_val,'UniformOutput',0);
    L_sum = cellfun(@(x) cumsum(x),L_val,'UniformOutput',0);
    % use half of the average (NAveJ) or maximum (NMajJ) at the
    % node as the overlap distance to avoid. L_idx is the index
    % into the flipped edgelist.
    L_idx = cellfun(@(x,y) find(x>=y/2,1),L_sum,num2cell(NMajJ)','UniformOutput',0);
    % if empty then the edge is within the overlap. Set the index
    % to be inf initially, it will be set to half the width in the
    % next step
    L_max = cellfun('isempty',L_idx);
    L_idx(L_max) = {inf};
    % set the maximum index as N_pix-1 and subtract from N_pix
    last = N_pix - min(cell2mat(L_idx)',max(round(N_pix.*0.5)-1,1));
    last(last<1) = 1;
    last(last>N_pix) = N_pix(last>N_pix);
    % If the edge width is equal to or greater than the penultimate width of
    % all edges at the node i, or the node degree is 2 or less, then keep the
    % full length of the edge. i.e. set the final index to N_pix;
    idx =  width >= NMidJ | NDegJ <= 2;
    %idx =  NDegJ <= 2 & NPerimJ == 0;
    % Combine the sets of indices
    last(idx) = N_pix(idx);
    % now collect the linear index of the pixels representing the center of the
    % edge using the first and last indices
    edgelist_center = cellfun(@(x,f1,l1) [x(f1:l1,1),x(f1:l1,2)],edgelist,num2cell(first'),num2cell(last'),'UniformOutput',0);
    % Convert the selected pixels to a linear index
    P_idx = cellfun(@(x) sub2ind([nY nX],x(:,1),x(:,2)),edgelist_center,'UniformOutput',0);
    % Get the intensity for each pixel from the original image using P_idx
    im = single(im);
    I_val = cellfun(@(x) im(x),P_idx,'UniformOutput',0);
    I_mean = cellfun(@mean, I_val);
    I_std = cellfun(@std, I_val);
    G_veins.Edges.Intensity = I_mean';
    G_veins.Edges.Intensity_cv = I_std'./I_mean';
    % Get the probability for each pixel from the cnn image using P_idx
    im_cnn = single(im_cnn);
    P_val = cellfun(@(x) im_cnn(x),P_idx,'UniformOutput',0);
    P_mean = cellfun(@mean, P_val);
    P_std = cellfun(@std, P_val);
    G_veins.Edges.Probability = P_mean';
    G_veins.Edges.Probability_cv = P_std'./P_mean';
    % Get the length as the difference in euclidean distance between pixels
    CL_val = cellfun(@(x) hypot(diff(x(:,1)),diff(x(:,2))),edgelist_center,'UniformOutput',0);
    CL_sum = cellfun(@sum, CL_val);
    % Get the width from the integral granulometry
    CW_val = cellfun(@(x) W_pixels(x),P_idx,'UniformOutput',0);
    CW_mean = cellfun(@mean, CW_val);
    CW_std = cellfun(@std, CW_val);
    CW_cv = CW_std./CW_mean;
    % get the average orientation of the center-width region. These
    % will tend to be in the range pi/2 to -pi/2 because nodei for all
    % edges is likely to be to the left of node j because of the
    % re-ordering in the graph.
    [midy, midx] = ind2sub([nY,nX],G_veins.Edges.M_pix);
    mid = mat2cell([midy midx],ones(length(midx),1),2)';
    % i-mid for y, mid-i for x for ij and j-mid for y, mid-i for x for ji
    % flip image axis so positive is counterclockwise from
    % horizontal
    CO_ij = cellfun(@(x,y) atan2(x(1,1)-y(1,1), y(1,2)-x(1,2)),edgelist_center,mid,'UniformOutput',1);
    CO_ji = cellfun(@(x,y) atan2(x(end,1)-y(1,1), y(1,2)-x(end,2)),edgelist_center,mid,'UniformOutput',1);
    % update the edgetable
    G_veins.Edges.Length = CL_sum';
    G_veins.Edges.Width = CW_mean';
    G_veins.Edges.Width_cv = CW_cv';
    G_veins.Edges.Or_ij = CO_ij';
    G_veins.Edges.Or_ji = CO_ji';
    % calculate the tortuosity as the ratio of the distance between the end
    % points (chord length) and the total length
    CL_chord = cellfun(@(x) hypot(x(1,1)-x(end,1),x(1,2)-x(end,2)),edgelist_center);
    G_veins.Edges.Tortuosity = CL_sum'./CL_chord';
    G_veins.Edges.Tortuosity(isinf(G_veins.Edges.Tortuosity)) = 1;
    % calculate the center-weighted radius
    R = G_veins.Edges.Width/2;
    % calculate the center-weighted area
    G_veins.Edges.Area = pi.*(R.^2);
    % calculate the center-weighted surface area
    G_veins.Edges.SurfaceArea = pi.*2.*R.*G_veins.Edges.Length ;
    % calculate the center-weighted volume
    G_veins.Edges.Volume = G_veins.Edges.Length.*G_veins.Edges.Area;
    % calculate the center-weighted resistance to flow for area or Poiseuille flow
    G_veins.Edges.R2 = G_veins.Edges.Length./(R.^2);
    G_veins.Edges.R4 = G_veins.Edges.Length./(R.^4);
    % find the feature edges and set them to the max
    F_idx = find(strcmp(G_veins.Edges.Type,'F'));
    G_veins.Edges.Width(F_idx) = max(CW_mean);
    G_veins.Edges.Width_cv(F_idx) = 0;
    % recalculate the node parameters from the center-width estimate
    rows = (1:size(A,1))';
    % calculate a weighted adjacency matrix for the center width
    A = sparse(i,j,double(G_veins.Edges.Width),nN,nN);
    A = A + A.' - diag(diag(A));
    G_veins.Nodes.node_Strength = full(sum(A,2));
    % calculate a weighted adjacency matrix for orientation for
    % edges ij
    O = sparse(i,j,double(G_veins.Edges.Or_ij),nN,nN);
    % swap the order of the nodes and subtract pi to get the
    % orientation of edges ji
    Oji = sparse(j,i,double(G_veins.Edges.Or_ji),nN,nN);
    O = O + Oji - diag(diag(O));
    % Calculate the initial max edge width incident at the node (initially
    % in double precision)
    [edge_Maj, max_idx] = max(A,[],2);
    edge_Maj = full(edge_Maj);
    mx_idx = sub2ind(size(A),rows,max_idx);
    % get the orientation of the largest edge from the orientation adjacency
    % matrix using the max_idx index
    G_veins.Nodes.node_Omaj = full(O(mx_idx));
    %G_veins.Nodes.node_Omaj(abs(G_veins.Nodes.node_Omaj)>180) = mod(G_veins.Nodes.node_Omaj(abs(G_veins.Nodes.node_Omaj)>180),180);
    % Calculate the minimum edge width. To calculate the min of a
    % sparse matrix, take the negative and then add back the maximum. This
    % avoids finding a full array of zeros!
    MaxA = sparse(i,j,max(edge_Maj),nN,nN);
    MaxA = MaxA + MaxA.' - diag(diag(MaxA));
    B = -A + MaxA;
    [edge_Min, min_idx] = max(B,[],2);
    mn_idx = sub2ind(size(A),rows,min_idx);
    % convert back to absolute positive value by negating and adding back the max
    G_veins.Nodes.node_Min = -full(edge_Min)+max(edge_Maj);
    % get the orentation of the weakest edge from the orientation adjacency
    % matric and the min_idx
    G_veins.Nodes.node_Omin = full(O(mn_idx));
    %G_veins.Nodes.node_Omin(abs(G_veins.Nodes.node_Omin)>180) = mod(G_veins.Nodes.node_Omin(abs(G_veins.Nodes.node_Omin)>180),180);
    % Calculate the width of the penultimate edge width by removing the max
    % values from the adjacency matrix and recalculating max for the remainder
    Amid = A;
    Amid(mx_idx) = 0;
    [edge_Mid, mid_idx] = max(Amid,[],2);
    pn_idx = sub2ind(size(A),rows,mid_idx);
    G_veins.Nodes.node_Mid = full(edge_Mid);
    % get the orientation of the penultimate edge from the orientation
    % adjacency matrix using the pn_idx
    G_veins.Nodes.node_Omid = full(O(pn_idx));
    G_veins.Nodes.node_Maj = edge_Maj;
    G_veins.Nodes.node_Average = G_veins.Nodes.node_Strength./G_veins.Nodes.node_Degree;
    % tidy up results for k=1 nodes
    idx = G_veins.Nodes.node_Degree == 1;
    G_veins.Nodes.node_Mid(idx) = nan;
    G_veins.Nodes.node_Min(idx) = nan;
    G_veins.Nodes.node_Omid(idx) = nan;
    G_veins.Nodes.node_Omin(idx) = nan;
    % calculate the ratios for the radii of smallest to largest and
    % intermediate to largest
    G_veins.Nodes.node_Min_Maj = G_veins.Nodes.node_Min./G_veins.Nodes.node_Maj;
    G_veins.Nodes.node_Mid_Maj = G_veins.Nodes.node_Mid./G_veins.Nodes.node_Maj;
    G_veins.Nodes.node_Min_Mid = G_veins.Nodes.node_Min./G_veins.Nodes.node_Mid;
    % calculate the absolute angles around the branch
    G_veins.Nodes.node_Omin_Omaj = pi - abs(pi - abs(G_veins.Nodes.node_Omin-G_veins.Nodes.node_Omaj));
    G_veins.Nodes.node_Omid_Omaj = pi - abs(pi - abs(G_veins.Nodes.node_Omid-G_veins.Nodes.node_Omaj));
    G_veins.Nodes.node_Omin_Omid = pi - abs(pi - abs(G_veins.Nodes.node_Omin-G_veins.Nodes.node_Omid));
end

function [gray_CW,width,coded_CW,coded_FW] = fnc_coded_skeleton(app,im,sk,bw_mask,G_veins,edgelist,edgelist_center,sk_width,cmap)
    [nY,nX] = size(sk);
    % set up a blank image
    gray_CW = zeros([nY,nX], 'single');
    CW_center = zeros([nY,nX], 'single');
    CW_full = zeros([nY,nX], 'single');
    width = zeros([nY,nX], 'single');
    % get the value of the edges from the graph. These are sorted
    % automatically when the graph is set up in order of node i
    E = G_veins.Edges.Width;
    % get just the edges (not any features)
    E_idx = contains(G_veins.Edges.Type,'E');
    E = E(E_idx);
    % get the linear pixel index for the center width each edge excluding the features
    CW_idx = cellfun(@(x) sub2ind([nY nX],x(:,1),x(:,2)),edgelist_center(E_idx),'UniformOutput',0);
    % concatenate all the pixels indices to give a single vector
    P_CW = cat(1,CW_idx{:});
    % calculate an edge width skeleton based on the central width
    % for the selected edges
    V_CW = num2cell(E);
    % duplicate the value for the number of pixels in the center part of each edge
    V_coded_idx = cellfun(@(x,y) repmat(y,length(x),1), CW_idx, V_CW','UniformOutput',0);
    % concatenate all the values into a single vector
    V_CW_all = cat(1,V_coded_idx{:});
    % set the edge values to the central width for the center part of each
    % edge. The CW_pixels image is a grayscale image with the intensity
    % of the skeleton making up each edge set to the width in pixels. The
    % overlap region is set to zero.
    gray_CW(P_CW) = single(V_CW_all);
    % To construct the color-coded skeleton, normalise the range between 2 and
    % 256 as an index into the colourmap
    Emin = min(E);
    Emax = max(E);
    cmap_idx = ceil(254.*((E-Emin)./(Emax-Emin)))+2;
    % set any nan values to 1
    cmap_idx(isnan(cmap_idx)) = 1;
    % convert to a cell array
    V_coded = num2cell(cmap_idx);
    % duplicate the value for the number of pixels in each edge
    V_coded_idx = cellfun(@(x,y) repmat(y,length(x),1), CW_idx, V_coded','UniformOutput',0);
    % concatenate all the values into a single vector
    V_coded_all = cat(1,V_coded_idx{:});
    % set the edge values to the central width for each edge in the image
    % scaled between the min and the max
    CW_center(P_CW) = single(V_coded_all);
    if sk_width > 1
        sk_D = imdilate(sk, ones(sk_width));
        CW_center = imdilate(CW_center, ones(sk_width));
    else
        sk_D = sk;
    end
    im2 = im;%uint8(255.*im);
    im2(sk_D) = 0;
    im_rgb = uint8(255.*ind2rgb(im2,gray(256)));
    sk_rgb = uint8(255.*ind2rgb(uint8(CW_center),cmap));
    coded_CW = imadd(im_rgb,sk_rgb);
    % add the boundaries of the mask
    coded_CW = imoverlay(coded_CW,bwperim(bw_mask),'m');
    % Repeat the process but this time calculate the full pixel skeleton
    % (including the overlap regions) and coded by the center weight for
    % export. Get the linear pixel index for the full width each edge excluding the features
    FW_idx = cellfun(@(x) sub2ind([nY nX],x(:,1),x(:,2)),edgelist(E_idx),'UniformOutput',0);
    % concatenate all the pixels indices to give a single vector
    P_FW = cat(1,FW_idx{:});
    % calculate an edge width skeleton based on the central width
    % for the selected edges
    V_FW = num2cell(E);
    % duplicate the value for the number of pixels in each edge
    V_FW_idx = cellfun(@(x,y) repmat(y,length(x),1), FW_idx, V_FW','UniformOutput',0);
    % concatenate all the values into a single vector
    V_FW_all = cat(1,V_FW_idx{:});
    % set the edge values to the central width for each edge in the image
    width(P_FW) = single(V_FW_all);
    % make sure that the junctions are set to the local maximum, so that
    % junctions are preserved as part of the strongest edge
    temp = colfilt(width,[3 3],'sliding',@max);
    bp = bwmorph(sk,'branchpoints');
    % The width image is a complete skeleton with no breaks in the overlap
    % region coded by the center-weighted thickness of the edge
    width(bp) = temp(bp);
    % % set the masked regions to -1
    width(~bw_mask) = -1;
    %
    %Calculate complete coded skeleton image using the center-weighted width
    % duplicate the coded width value for the number of pixels in each full edge
    V_FW_idx = cellfun(@(x,y) repmat(y,length(x),1), FW_idx, V_coded','UniformOutput',0);
    % concatenate all the values into a single vector
    V_FW_all = cat(1,V_FW_idx{:});
    % set the edge values to the central width for each edge in the image
    CW_full(P_FW) = single(V_FW_all);
    % set the edge values to the central width for each edge in the image
    % scaled between the min and the max
    temp = colfilt(CW_full,[3 3],'sliding',@max);
    % The width image is a complete skeleton with no breaks in the overlap
    % region coded by the center-weighted thickness of the edge
    CW_full(bp) = temp(bp);
    if sk_width > 1
        CW_full = imdilate(CW_full, ones(sk_width));
    end
    im_rgb = uint8(255.*ind2rgb(im,gray(256)));
    sk_rgb = uint8(255.*ind2rgb(uint8(CW_full),cmap));
    coded_FW = imadd(im_rgb,sk_rgb);
    % add the boundaries of the mask
    coded_FW = imoverlay(coded_FW,bwperim(bw_mask),'m');
end


function [G_veins, sk_polygon, bw_polygons, bw_areoles, total_area_mask, polygon_LM] = fnc_polygon_find(app,G_veins,bw_cnn,sk,skLoop,bw_mask)
    % remove any partial areas that are not fully bounded and therefore contact
    % the edge
    area = imclearborder(~sk & bw_mask,4);
    % remove any partial areoles that are touching any masked regions
    [r,c] = find(~bw_mask);
    area_mask = ~(bwselect(area | ~bw_mask,c,r,4));
    % get rid of any vestigial skeleton lines within the mask
    area_mask = bwmorph(area_mask, 'open');
    % fill the skeleton lines on the total area
    total_area_mask = imdilate(area, ones(3)) & area_mask;
    % remove isolated polygons
    total_area_mask = bwareafilt(total_area_mask, 1,'largest');
    % The polygons include the areoles and the vein itself.
    bw_polygons = ~skLoop & total_area_mask;
    % find areas that are too small (note bwareafilt does not work as it is not
    % possible to set connectivity to 4)
    CC = bwconncomp(bw_polygons,4);
    stats = regionprops(CC, 'Area');
    % arbitary threshold of ~10x10 pixels
    idx = find([stats.Area] > 100);
    bw_polygons  = ismember(labelmatrix(CC), idx);
    % The areoles exclude the full width of the veins
    bw_areoles = ~bw_cnn & total_area_mask & bw_polygons;
    % remove any orphan pixels
    bw_areoles = bwmorph(bw_areoles,'clean');
    % trim the skeleton to match
    sk_polygon = sk & total_area_mask;
    % construct a label matrix of areas including the veins
    LM = bwlabel(bw_polygons,4);
    % remove the skeleton in the calculation of the neighbouring
    % area
    LM(sk_polygon) = NaN;
    % find the neighbours of each edge
    Neighbour1 = colfilt(LM,[3 3],'sliding',@max);
    Neighbour2 = colfilt(LM,[3 3],'sliding',@(x) min(x,[],'Omitnan'));
    % Note some neighbours may link to the excluded areas and have a value of 0
    % add the neighbours to the graph
    G_veins.Edges.Ai = Neighbour1(G_veins.Edges.M_pix);
    G_veins.Edges.Aj = Neighbour2(G_veins.Edges.M_pix);
    % replace the skeleton pixels with zero
    LM(sk_polygon) = 0;
    % dilate the label matrix to include the pixel skeleton
    % LM = imdilate(LM, [0 1 0; 1 1 1; 0 1 0]);
    % polygon_LM = imdilate(LM, [0 1 0; 1 1 1; 0 1 0]);
    polygon_LM = imdilate(LM,ones(3));
    % trim off any extension beyond the boundary
    polygon_LM = polygon_LM.*total_area_mask;
end

function im_polygons_rgb = fnc_polygon_image(app,polygon_stats, sk_polygon, total_area_mask)
    [nY,nX] = size(total_area_mask);
    % construct a colour-coded area image from the log area
    idx = cat(1,polygon_stats.PixelIdxList);
    polygon_areas = [polygon_stats.Area];
    %
    logA = log10(polygon_areas);
    logA(isinf(logA)) = nan;
    Amin = 1;
    Amax = log10(sum(total_area_mask(:)));
    % normalise the range between 2 and 256 as an index into the
    % colourmap
    A_idx = ceil(254.*((logA-Amin)./(Amax-Amin)))+1;
    % set any nan values to 1
    A_idx(isnan(A_idx)) = 1;
    % duplicate the area value for the number of pixels in each edge
    A_val = cellfun(@(x,y) repmat(x,y,1), num2cell(A_idx),num2cell(polygon_areas),'UniformOutput',0);
    % concatenate all the values into a single vector
    A_all = cat(1,A_val{:});
    % set the edge values to the edge ID for each edge in the image
    %im_polygons = zeros(nY,nX);
    im_polygons = zeros(nY,nX, 'single');
    im_polygons(idx) = single(A_all);
    cmap = cool(256);
    cmap(1,:) = 1;
    % punch out the skeleton to make the polygons distinct
    im_polygons(sk_polygon) = 0;
    im_polygons = uint8(im_polygons);
    im_polygons_rgb = uint8(255.*ind2rgb(im_polygons,cmap));
end

function [areole_stats,polygon_stats] = fnc_polygon_analysis(app,bw_polygons,bw_areoles, polygon_LM,FullMetrics)
    % measure the stats for the polygonal regions using the label matrix
    if FullMetrics == 0
        % don't calculate the most time consuming metrics (ConvexArea,
        % Solidity) and the internal distance metrics.
        polygon_stats = regionprops(polygon_LM, ...
            'Area', ...
            'Centroid', ...
            'Eccentricity', ...
            'EquivDiameter', ...
            'MajorAxisLength', ...
            'MinorAxisLength', ...
            'Orientation', ...
            'Perimeter', ...
            'PixelIdxList');
    else
        P_stats = regionprops(polygon_LM, ...
            'Area', ...
            'Centroid', ...
            'ConvexArea', ...
            'Eccentricity', ...
            'EquivDiameter', ...
            'MajorAxisLength', ...
            'MinorAxisLength', ...
            'Orientation', ...
            'Perimeter', ...
            'Solidity', ...
            'PixelIdxList');
        % get the maximum distance to the skeleton for each area
        D_stats = regionprops(polygon_LM,bwdist(~bw_polygons)+0.5,'MaxIntensity','MeanIntensity');
        % check for any empty values as these will switch the format from a
        % number to a cell
        test4empty = cellfun(@isempty,{D_stats.MaxIntensity});
        if any(test4empty)
            % replace the empty ell with the minimum distance of 0.5 pixel
            [D_stats(test4empty).MaxIntensity] = deal(0.5);
        end
        % rename the fields
        [D_stats.('MaxDistance')] = D_stats.('MaxIntensity');
        D_stats = rmfield(D_stats,'MaxIntensity');
        % get the average distance to the skeleton for each area
        [D_stats.('MeanDistance')] = D_stats.('MeanIntensity');
        D_stats = rmfield(D_stats,'MeanIntensity');
        names = [fieldnames(P_stats); fieldnames(D_stats)];
        % combine the two stats arrays
        polygon_stats = cell2struct([struct2cell(P_stats);struct2cell(D_stats)],names,1);
    end
    % calculate additional parameters
    ID = num2cell(1:length(polygon_stats));
    Circularity = num2cell((4.*pi.*[polygon_stats.Area])./([polygon_stats.Perimeter].^2));
    Elongation = num2cell([polygon_stats.MajorAxisLength]./[polygon_stats.MinorAxisLength]);
    Roughness = num2cell(([polygon_stats.Perimeter].^2)./[polygon_stats.Area]);
    [polygon_stats(:).Circularity] = deal(Circularity{:});
    [polygon_stats(:).Elongation] = deal(Elongation{:});
    [polygon_stats(:).Roughness] = deal(Roughness{:});
    [polygon_stats(:).ID] = deal(ID{:});
    % modify the label matrix to only include the areoles, but with the same ID
    % as the polygons
    areole_LM = polygon_LM;
    areole_LM(~bw_areoles) = 0;
    % run all the stats for the areoles
    if FullMetrics == 0
        % don't calculate the most time consuming metrics (ConvexArea,
        % Solidity) and don't calculate the internal distance measures
        areole_stats = regionprops(areole_LM, ...
            'Area', ...
            'Centroid', ...
            'Eccentricity', ...
            'EquivDiameter', ...
            'MajorAxisLength', ...
            'MinorAxisLength', ...
            'Orientation', ...
            'Perimeter', ...
            'PixelIdxList');
    else
        A_stats = regionprops(areole_LM, ...
            'Area', ...
            'Centroid', ...
            'ConvexArea', ...
            'Eccentricity', ...
            'EquivDiameter', ...
            'MajorAxisLength', ...
            'MinorAxisLength', ...
            'Orientation', ...
            'Perimeter', ...
            'Solidity', ...
            'PixelIdxList');
        % get the maximum distance to the skeleton for each area
        D_stats = regionprops(areole_LM,bwdist(~bw_areoles)+0.5,'MaxIntensity','MeanIntensity');
        % find any empty cells in the maxIntensity and replace with nan. This
        % ensures that the metric is saved as a number rather than a cell array
        test4empty = cellfun(@isempty,{D_stats.MaxIntensity});
        if any(test4empty)
            [D_stats(test4empty).MaxIntensity] = deal(0.5);
        end
        % rename the fields
        [D_stats.('MaxDistance')] = D_stats.('MaxIntensity');
        D_stats = rmfield(D_stats,'MaxIntensity');
        % get the average distance to the skeleton for each area
        [D_stats.('MeanDistance')] = D_stats.('MeanIntensity');
        D_stats = rmfield(D_stats,'MeanIntensity');
        names = [fieldnames(A_stats); fieldnames(D_stats)];
        % combine the two stats arrays
        areole_stats = cell2struct([struct2cell(A_stats);struct2cell(D_stats)],names,1);
    end
    % calculate additional parameters
    ID = num2cell(1:length(areole_stats));
    Circularity = num2cell((4.*pi.*[areole_stats.Area])./([areole_stats.Perimeter].^2));
    Elongation = num2cell([areole_stats.MajorAxisLength]./[areole_stats.MinorAxisLength]);
    Roughness = num2cell(([areole_stats.Perimeter].^2)./[areole_stats.Area]);
    [areole_stats(:).Circularity] = deal(Circularity{:});
    [areole_stats(:).Elongation] = deal(Elongation{:});
    [areole_stats(:).Roughness] = deal(Roughness{:});
    [areole_stats(:).ID] = deal(ID{:});
end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.param.ScaleFactor = 1;
            app.param.Channel = 'G';
            app.param.ClaheSkip = 0;
            app.param.ClaheRadius = 400;
            app.param.ClaheGain = 0.05;
            app.param.CNNEnsemble = 6;
            app.param.CNNStride = 200;
            % update the controls
            app.ScaleFactorEditField.Value = app.param.ScaleFactor;
            app.ChannelDropDown.Value = app.param.Channel;
            app.ClaheSkipCheckBox.Value = app.param.ClaheSkip;
            app.ClaheRadiuslEditField.Value = app.param.ClaheRadius;
            app.ClaheGainEditField.Value = app.param.ClaheGain;
            app.CNNEnsembleDropDown.Items = string(1:38);
            app.CNNEnsembleDropDown.Value = num2str(app.param.CNNEnsemble);
            app.CNNStrideSlider.Value = app.param.CNNStride;
            %
            funcSkipClahe(app)
            % start off with controls disabled
            h = app.ProcessingPanel.Children;
            set(h,'Enable','off');
            h = app.ProgramPanel.Children;
            set(h,'Enable','off');
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            target = app.TargetDropDown.Value;
            funcLoadImage(app,target)
            app.StatusLabel.Text = 'Please enter the pixel resolution in microns per pixel';
        end

        % Value changed function: TargetDropDown
        function TargetDropDownValueChanged(app, event)
           
        end

        % Value changed function: MicronPerPixelEditField
        function MicronPerPixelEditFieldValueChanged(app, event)
            % set the scaling factor between the current image and the resolution used for training the CNN
            app.param.ScaleFactor = round(app.MicronPerPixelEditField.Value/1.6807,2);
            app.ScaleFactorEditField.Value = app.param.ScaleFactor;
            h = app.ProcessingPanel.Children;
            set(h,'Enable','on')
            funcSkipClahe(app)
            h = app.ProgramPanel.Children;
            set(h,'Enable','on')
            app.StatusLabel.Text = ' ';
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            funcImageDisplay(app,'original')
            app.StatusLabel.Text = 'Please draw a Region-Of-Interest on the image';
            hROI = drawrectangle(app.DisplayAxes);
            wait(hROI)
            pos = round(hROI.Position);
            delete(hROI)
            temp = app.images.original(pos(2):pos(2)+pos(4), pos(1):pos(1)+pos(3),:);
            app.images.crop  = funcChannelSelect(app,temp);
            funcImageDisplay(app, 'crop')
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            app.param.Channel = app.ChannelDropDown.Value;
            
        end

        % Value changed function: ClaheSkipCheckBox
        function ClaheSkipCheckBoxValueChanged(app, event)
            funcSkipClahe(app)
        end

        % Callback function
        function ClaheChannelEditFieldValueChanged(app, event)
            app.param.ClaheChannel = app.ClaheChannelEditField.Value;
            
        end

        % Value changed function: ClaheRadiuslEditField
        function ClaheRadiuslEditFieldValueChanged(app, event)
            app.param.ClaheRadius = app.ClaheRadiuslEditField.Value;
            
        end

        % Value changed function: ClaheGainEditField
        function ClaheGainEditFieldValueChanged(app, event)
            app.param.ClaheGain = app.ClaheGainEditField.Value;
            
        end

        % Button pushed function: ClaheLoadButton
        function ClaheLoadButtonPushed(app, event)
            funcLoadImage(app)
            app.images.crop = app.images.original;
            app.images.clahe = app.images.original;
            app.images.original = app.images.clahe;
            app.DisplayImageDropDown.Items = {'original';'crop';'clahe'};
        end

        % Button pushed function: ClaheSaveButton
        function ClaheSaveButtonPushed(app, event)
            imwrite(app.images.clahe,fullfile(app.var.dir_in,[app.var.fname '_X_CLAHE.png']),'png')
        end

        % Button pushed function: ClaheButton
        function ClaheButtonPushed(app, event)
            radius = app.param.ClaheRadius/app.param.ScaleFactor; % local radius (pixels) for contrast-limited adaptive histogram equalization (CLAHE)
            gain = app.param.ClaheGain; % contrast enhancement (from 0 to 1)
            if size(app.images.crop,3) > 1
                grayimg = app.images.crop(:,:,app.param.ClaheChannel);
            else
                grayimg = app.images.crop;
            end
            % normalise
            numTiles = max([2,2],floor(size(grayimg)./radius));
            grayimg = imadjust(grayimg);
            grayimg = imguidedfilter(grayimg,grayimg,'NeighborhoodSize',[7 7]);
            grayimg = adapthisteq(grayimg, 'NumTiles', numTiles, 'ClipLimit', gain);
            app.images.clahe = imresize(grayimg,app.param.ScaleFactor,'Method','bilinear');
            % enhance contrast using an adaptive histogram equalisation
            funcImageDisplay(app, 'clahe')
        end

        % Value changed function: CNNEnsembleDropDown
        function CNNEnsembleDropDownValueChanged(app, event)
            value = app.CNNEnsembleDropDown.Value;
            
        end

        % Button pushed function: CNNRunButton
        function CNNRunButtonPushed(app, event)
            app.StatusLabel.Text = 'Initialising the CNN';
            % select networks
            %             dir_in = uigetdir('Select folder with trained models');
            dir_in = 'C:\Users\dops0035\Documents\Research\Matlab Working\LeafVeinAnalysis\cnn_model\Matlab models';
            % get all the trained networks in the current folder
            dir_struct = dir(dir_in);% read in the directory structure
            file_idx = ~[dir_struct.isdir];% strip out any directories
            [sorted_names,~] = sortrows({dir_struct(file_idx).name}');% sort the filenames in order
            % now show only files with selected extensions
            fnc_find_ext = @(file_ext) ~cellfun(@isempty, regexpi(sorted_names,['\w+' file_ext],'match')); % find any files with the appropriate extension
            included = cellfun(fnc_find_ext, {'.mat'}, 'UniformOutput',0);% feed array of extensions to the find function
            include_idx = max(cell2mat(included'),[],2);% collapse the boolean output into a linear index
            filenames = sorted_names(include_idx);
            % exclude poor performing networks
            exclude = [5,6,8,17,19,24,27,33,37,38];
            population = setdiff(1:38,exclude);
            ensemble = str2double(app.CNNEnsembleDropDown.Value);
            idx = randsample(population,ensemble);
            app.images.CNN = zeros([size(app.images.crop) ensemble]);
            stride = round(app.CNNStrideSlider.Value);
            if mod(stride,2) == 1
                stride = stride+1;
            end
            for iF = 1:ensemble
                fname = filenames{idx(iF)};
                app.StatusLabel.Text = ['Calculating the CNN prediction for iteration ' num2str(iF) ' using network ' fname '. This will take some time. Please wait...'];drawnow
                temp = load(fullfile(dir_in,fname));
                net = temp.net;
                myfun = @(block_struct) predict(net,block_struct.data);
                border_size = [(256-stride)/2 (256-stride)/2];
                cnn = blockproc(app.images.clahe,[stride stride],myfun,'PadPartialBlocks',true,'PadMethod','replicate','BorderSize',border_size);
                app.images.CNN(:,:,iF) = imresize(cnn(1:size(app.images.clahe,1),1:size(app.images.clahe,2)),size(app.images.crop));
            end
            funcImageDisplay(app,'CNN')
             app.DisplayImageDropDown.Items = unique([app.DisplayImageDropDown.Items, 'CNNoverlay']);

        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
            threshold = 0.65;
            app.StatusLabel.Text = ['Skeleton extraction using threshold ' num2str(threshold)]; drawnow
            [bw_cnn, sk, skLoop, skTree] = fnc_skeleton(app,threshold);
            funcImageDisplay(app,'sk')
            [im_distance, ~] = bwdist(~app.images.bw,'Euclidean');
            % extract the initial width along the skeleton from the distance transform
            W_pixels = zeros(size(im_distance),'single');
            W_pixels(app.images.sk) = single(im_distance(sk).*2);
            app.StatusLabel.Text = 'Extracting edges. Please wait...';drawnow
            [edgelist, edgeim] = edgelink(sk);
            % find any self loops and split them
            app.StatusLabel.Text = 'Resolving self loops. Please wait...';drawnow
            [edgelist, edgeim] = fnc_resolve_self_loops(app,edgelist,edgeim);
            app.StatusLabel.Text = 'Resolving duplicate edges. Please wait...';drawnow
            [edgelist, ~] = fnc_resolve_duplicates(app,edgelist,edgeim);
            app.StatusLabel.Text = 'Constructing the weighted graph. Please wait...';drawnow
            [G_veins,edgelist] = fnc_weighted_graph(app,edgelist,W_pixels,skTree);
            app.StatusLabel.Text = 'Refining the width. Please wait...';drawnow
            im = app.images.crop;
            im_cnn = app.images.CNN;
            [G_veins,edgelist_center] = fnc_refine_width(app,G_veins,edgelist,im,im_cnn,W_pixels);
            app.StatusLabel.Text = 'CAlculating coded image. Please wait...';drawnow
            bw_mask = true(size(im));
            sk_width = 1;
            cmap = jet(256);
            [CW_pixels,im_width,coded_CW,coded_FW] = fnc_coded_skeleton(app,im,sk,bw_mask,G_veins,edgelist,edgelist_center,sk_width,cmap);
            app.images.coded = coded_FW;
            funcImageDisplay(app,'coded')
            app.StatusLabel.Text = 'Finding the polygon and areole areas. Please wait...';drawnow
            
            [G_veins, sk_polygon, bw_polygons, bw_areoles, total_area_mask, polygon_LM] = fnc_polygon_find(app,G_veins,bw_cnn,sk,skLoop,bw_mask);
            app.StatusLabel.Text = 'Polygon analysis. Please wait...';drawnow
            FullMetrics = 1
            [areole_stats,polygon_stats] = fnc_polygon_analysis(app,bw_polygons,bw_areoles, polygon_LM,FullMetrics);
            app.StatusLabel.Text = 'Constructing colour-coded polygon image. Please wait...';drawnow
            im_areoles_rgb = fnc_polygon_image(app,areole_stats, sk_polygon, total_area_mask);
            app.images.areolesRGB = im_areoles_rgb;
            funcImageDisplay(app,'areolesRGB')
        end

        % Value changed function: DisplayImageDropDown
        function DisplayImageDropDownValueChanged(app, event)
            target = app.DisplayImageDropDown.Value;
            funcImageDisplay(app,target)
        end

        % Callback function
        function ZoomSliderValueChanged(app, event)
            value = app.ZoomSlider.Value;
            zoom(app.DisplayAxes,value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LeafVeinGUI = uifigure('Visible', 'off');
            app.LeafVeinGUI.Name = 'UI Figure';
            app.LoadPanel = uipanel(app.LeafVeinGUI);
            app.LoadPanel.Title = 'Load';
            app.LoadImageButton = uibutton(app.LoadPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ImageLabel = uilabel(app.LoadPanel);
            app.ImageLabel.Text = 'Image';
            app.micronperpixelEditFieldLabel = uilabel(app.LoadPanel);
            app.micronperpixelEditFieldLabel.Text = 'micron per pixel';
            app.MicronPerPixelEditField = uieditfield(app.LoadPanel, 'numeric');
            app.MicronPerPixelEditField.ValueChangedFcn = createCallbackFcn(app, @MicronPerPixelEditFieldValueChanged, true);
            app.DimensionsTextAreaLabel = uilabel(app.LoadPanel);
            app.DimensionsTextAreaLabel.Text = 'Dimensions';
            app.DimensionsTextArea = uitextarea(app.LoadPanel);
            app.TargetDropDownLabel = uilabel(app.LoadPanel);
            app.TargetDropDownLabel.Text = 'Target';
            app.TargetDropDown = uidropdown(app.LoadPanel);
            app.TargetDropDown.Items = {'original', 'clahe', 'CNN'};
            app.TargetDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetDropDownValueChanged, true);
            app.TargetDropDown.Value = 'original';
            app.rescalefactorLabel = uilabel(app.LoadPanel);
            app.rescalefactorLabel.Text = 'rescale factor';
            app.ScaleFactorEditField = uieditfield(app.LoadPanel, 'numeric');
            app.DisplayPanel = uipanel(app.LeafVeinGUI);
            app.DisplayPanel.Title = 'Display';
            app.DisplayAxes = uiaxes(app.DisplayPanel);
            title(app.DisplayAxes, 'Title')
            xlabel(app.DisplayAxes, 'X')
            ylabel(app.DisplayAxes, 'Y')
            app.ProgramPanel = uipanel(app.LeafVeinGUI);
            app.ProgramPanel.Title = 'CNN Prediction';
            app.CNNRunButton = uibutton(app.ProgramPanel, 'push');
            app.CNNRunButton.ButtonPushedFcn = createCallbackFcn(app, @CNNRunButtonPushed, true);
            app.CNNRunButton.Text = 'Run CNN';
            app.CNNSaveButton = uibutton(app.ProgramPanel, 'push');
            app.CNNSaveButton.Text = 'Save CNN';
            app.EnsembleDropDownLabel = uilabel(app.ProgramPanel);
            app.EnsembleDropDownLabel.Text = 'Ensemble';
            app.CNNEnsembleDropDown = uidropdown(app.ProgramPanel);
            app.CNNEnsembleDropDown.Items = {'"6"'};
            app.CNNEnsembleDropDown.ValueChangedFcn = createCallbackFcn(app, @CNNEnsembleDropDownValueChanged, true);
            app.CNNEnsembleDropDown.Value = '"6"';
            app.PixelStrideLabel = uilabel(app.ProgramPanel);
            app.PixelStrideLabel.Text = {'Pixel'; 'Stride'};
            app.CNNStrideSlider = uislider(app.ProgramPanel);
            app.CNNStrideSlider.Limits = [50 250];
            app.CNNStrideSlider.MajorTicks = [50 100 150 200 250];
            app.CNNStrideSlider.Value = 200;
            app.DisplayControlsPanel = uipanel(app.LeafVeinGUI);
            app.DisplayControlsPanel.Title = 'Display Controls';
            app.DisplayImageLabel = uilabel(app.DisplayControlsPanel);
            app.DisplayImageLabel.Text = 'Display Image';
            app.DisplayImageDropDown = uidropdown(app.DisplayControlsPanel);
            app.DisplayImageDropDown.Items = {'original'};
            app.DisplayImageDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayImageDropDownValueChanged, true);
            app.DisplayImageDropDown.Value = 'original';
            app.ProcessingPanel = uipanel(app.LeafVeinGUI);
            app.ProcessingPanel.Title = 'Processing';
            app.CropButton = uibutton(app.ProcessingPanel, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.ClaheSaveButton = uibutton(app.ProcessingPanel, 'push');
            app.ClaheSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ClaheSaveButtonPushed, true);
            app.ClaheSaveButton.Text = 'Save';
            app.ClaheLoadButton = uibutton(app.ProcessingPanel, 'push');
            app.ClaheLoadButton.ButtonPushedFcn = createCallbackFcn(app, @ClaheLoadButtonPushed, true);
            app.ClaheLoadButton.Text = 'Load';
            app.ClaheButton = uibutton(app.ProcessingPanel, 'push');
            app.ClaheButton.ButtonPushedFcn = createCallbackFcn(app, @ClaheButtonPushed, true);
            app.ClaheButton.Text = 'CLAHE';
            app.RadiusLabel = uilabel(app.ProcessingPanel);
            app.RadiusLabel.Text = 'Radius';
            app.ClaheRadiuslEditField = uieditfield(app.ProcessingPanel, 'numeric');
            app.ClaheRadiuslEditField.Limits = [1 1000];
            app.ClaheRadiuslEditField.RoundFractionalValues = 'on';
            app.ClaheRadiuslEditField.ValueDisplayFormat = '%11.0f';
            app.ClaheRadiuslEditField.ValueChangedFcn = createCallbackFcn(app, @ClaheRadiuslEditFieldValueChanged, true);
            app.ClaheRadiuslEditField.Value = 1;
            app.GainLabel = uilabel(app.ProcessingPanel);
            app.GainLabel.Text = 'Gain';
            app.ClaheGainEditField = uieditfield(app.ProcessingPanel, 'numeric');
            app.ClaheGainEditField.Limits = [0 1];
            app.ClaheGainEditField.ValueDisplayFormat = '%11.4f';
            app.ClaheGainEditField.ValueChangedFcn = createCallbackFcn(app, @ClaheGainEditFieldValueChanged, true);
            app.ClaheGainEditField.Value = 0.1;
            app.ClaheSkipCheckBox = uicheckbox(app.ProcessingPanel);
            app.ClaheSkipCheckBox.ValueChangedFcn = createCallbackFcn(app, @ClaheSkipCheckBoxValueChanged, true);
            app.ClaheSkipCheckBox.Text = 'Skip CLAHE';
            app.ChannelDropDownLabel = uilabel(app.ProcessingPanel);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.ProcessingPanel);
            app.ChannelDropDown.Items = {'R', 'G', 'B', 'R-G', 'R-B', 'G-R', 'G-B', 'B-R', 'B-G', 'R^2/G', 'R^2/B', 'G^2/R', 'G^2/B', 'B^2/R', 'B^2/G'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = 'R';
            app.StatusLabel = uilabel(app.LeafVeinGUI);
            app.StatusLabel.Text = 'Status';
            app.AnalysisPanel = uipanel(app.LeafVeinGUI);
            app.AnalysisPanel.Title = 'Analysis';
            app.AnalysisButton = uibutton(app.AnalysisPanel, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Text = 'Analysis';
            % Show the figure after all components are created
            app.LeafVeinGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LeafVeinCNN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LeafVeinGUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LeafVeinGUI)
        end
    end
end